classdef CSSE435_PiProject_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        BgcolorDropDown       matlab.ui.control.DropDown
        BgcolorDropDownLabel  matlab.ui.control.Label
        WidthSpinner          matlab.ui.control.Spinner
        WidthSpinnerLabel     matlab.ui.control.Label
        YKnob                 matlab.ui.control.Knob
        YKnobLabel            matlab.ui.control.Label
        XKnob                 matlab.ui.control.Knob
        XKnobLabel            matlab.ui.control.Label
        LinecolorDropDown     matlab.ui.control.DropDown
        ColorSelectionLabel   matlab.ui.control.Label
        SAVEButton            matlab.ui.control.Button
        RESETButton           matlab.ui.control.Button
        Panel                 matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
        Image                 matlab.ui.control.Image
    end

    
    properties (Access = private)
        mqClient
        currentLine
        currentColor = 'k'
        currentWidth = 2
        allLines = gobjects(0)
        penX = 0.5   % pen position 0..1 (same as the Pi)
        penY = 0.5
    end

    methods (Access = private)

        function sendMessage(app, messageType, payload)
            messageStruct.type = messageType;
            messageStruct.payload = payload;
            message = jsonencode(messageStruct);
            fprintf("Sending: %s\n", message);
            app.mqClient.write("me435/smithic/to_pi", message);
        end

        function myCallback(app, topic, message)
            fprintf("Received: %s\n", message);
            msg = jsondecode(message);
            switch msg.type
                case 'pots'
                    app.XKnob.Value = msg.payload.x;
                    app.YKnob.Value = msg.payload.y;
                    app.drawPoint(msg.payload.x, msg.payload.y);
                case 'color'
                    app.LinecolorDropDown.Value = msg.payload;
                    app.setColor();
                case 'width'
                    app.WidthSpinner.Value = msg.payload;
                    app.setWidth();
                case 'bg'
                    app.BgcolorDropDown.Value = msg.payload;
                    app.setBg();
                case 'reset'
                    app.clearDrawing();
                case 'save'
                    app.saveDrawing();
            end
        end

        function drawPoint(app, xNorm, yNorm)
            app.penX = xNorm;
            app.penY = yNorm;
            % Map pot range 0..1 onto the axes range -1..1
            addpoints(app.currentLine, 2*xNorm - 1, 2*yNorm - 1);
            drawnow limitrate     % keeps the GUI responsive at high message rates
        end

        function startNewLine(app)
            app.currentLine = animatedline(app.UIAxes, ...
            'Color', app.currentColor, 'LineWidth', app.currentWidth, ...
            'MaximumNumPoints', 1e5);
            app.allLines(end+1) = app.currentLine;
            addpoints(app.currentLine, 2*app.penX - 1, 2*app.penY - 1);  % start at the pen
        end

        function setColor(app)
            map = containers.Map({'Black','Green','Blue','Red'}, {'k','g','b','r'});
            app.currentColor = map(app.LinecolorDropDown.Value);
            app.startNewLine();   % old strokes keep their color
        end

        function setWidth(app)
            app.currentWidth = app.WidthSpinner.Value;
            app.startNewLine();   % old strokes keep their width
        end

        function setBg(app)
            app.UIAxes.Color = lower(app.BgcolorDropDown.Value);
            app.Panel.BackgroundColor = lower(app.BgcolorDropDown.Value);
        end

        function bgChanged(app)
            app.setBg();
            app.sendMessage("bg", app.BgcolorDropDown.Value);
        end

        function clearDrawing(app)
            delete(app.allLines);
            app.allLines = gobjects(0);
            app.startNewLine();
        end

        function saveDrawing(app)
            fileName = "etch_" + string(datetime('now', 'Format', 'yyyyMMdd_HHmmss')) + ".png";
            exportgraphics(app.UIAxes, fileName);
            fprintf("Saved %s\n", fileName);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.XLim = [-1 1];  app.UIAxes.YLim = [-1 1];
            app.mqClient = mqttclient("tcp://broker.hivemq.com");  % off campus. On campus: tcp://mosquitto.csse.rose-hulman.edu
            subscribe(app.mqClient, "me435/smithic/to_computer", "Callback", @app.myCallback);
            % Bg color dropdown: items + callback set here (same list as the Pi)
            app.BgcolorDropDown.Items = {'White', 'Yellow', 'Blue', 'Black'};
            app.BgcolorDropDown.Value = 'White';
            app.BgcolorDropDown.ValueChangedFcn = @(src, event) app.bgChanged();
            app.setBg();
            app.startNewLine();
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.clearDrawing();
            app.sendMessage("reset", []);
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            app.saveDrawing();
            app.sendMessage("save", []);
        end

        % Value changed function: LinecolorDropDown
        function LinecolorDropDownValueChanged(app, event)
            app.setColor();
            app.sendMessage("color", app.LinecolorDropDown.Value);
        end

        % Value changing function: XKnob
        function XKnobValueChanging(app, event)
            app.drawPoint(event.Value, app.YKnob.Value);
            app.sendMessage("pots", struct('x', round(event.Value, 3), 'y', round(app.YKnob.Value, 3)));
        end

        % Value changing function: YKnob
        function YKnobValueChanging(app, event)
            app.drawPoint(app.XKnob.Value, event.Value);
            app.sendMessage("pots", struct('x', round(app.XKnob.Value, 3), 'y', round(event.Value, 3)));
        end

        % Value changed function: WidthSpinner
        function WidthSpinnerValueChanged(app, event)
            app.setWidth();
            app.sendMessage("width", app.WidthSpinner.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'etch a sketch frame.png');
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            app.UIAxes.XLim = [-1 1];
            app.UIAxes.YLim = [-1 1];
            app.UIAxes.ZLim = [-1 1];
            app.UIAxes.CLimMode = 'manual';
            app.UIAxes.ALimMode = 'manual';
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.ColorSelectionLabel = uilabel(app.UIFigure);
            app.ColorSelectionLabel.Text = 'Line color:';
            app.LinecolorDropDown = uidropdown(app.UIFigure);
            app.LinecolorDropDown.Items = {'Black', 'Green', 'Blue', 'Red'};
            app.LinecolorDropDown.ValueChangedFcn = createCallbackFcn(app, @LinecolorDropDownValueChanged, true);
            app.LinecolorDropDown.Value = 'Black';
            app.XKnobLabel = uilabel(app.UIFigure);
            app.XKnobLabel.Text = 'XKnob';
            app.XKnob = uiknob(app.UIFigure, 'continuous');
            app.XKnob.Limits = [0 1];
            app.XKnob.MajorTicks = [];
            app.XKnob.ValueChangingFcn = createCallbackFcn(app, @XKnobValueChanging, true);
            app.XKnob.MinorTicksMode = 'manual';
            app.XKnob.Value = 0.5;
            app.YKnobLabel = uilabel(app.UIFigure);
            app.YKnobLabel.Text = 'YKnob';
            app.YKnob = uiknob(app.UIFigure, 'continuous');
            app.YKnob.Limits = [0 1];
            app.YKnob.MajorTicks = [];
            app.YKnob.ValueChangingFcn = createCallbackFcn(app, @YKnobValueChanging, true);
            app.YKnob.MinorTicksMode = 'manual';
            app.YKnob.Value = 0.5;
            app.WidthSpinnerLabel = uilabel(app.UIFigure);
            app.WidthSpinnerLabel.Text = 'Width';
            app.WidthSpinner = uispinner(app.UIFigure);
            app.WidthSpinner.Limits = [1 10];
            app.WidthSpinner.ValueChangedFcn = createCallbackFcn(app, @WidthSpinnerValueChanged, true);
            app.WidthSpinner.Value = 2;
            app.BgcolorDropDownLabel = uilabel(app.UIFigure);
            app.BgcolorDropDownLabel.Text = 'B.g. color:';
            app.BgcolorDropDown = uidropdown(app.UIFigure);
            app.BgcolorDropDown.Items = {'Black', 'Green', 'Blue', 'Red'};
            app.BgcolorDropDown.Value = 'Black';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CSSE435_PiProject_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end